classdef foot_markers < matlab.apps.AppBase

	% Properties that correspond to app components
	properties (Access = public)
		FootMarkersUIFigure  matlab.ui.Figure
		UITable_gc_info      matlab.ui.control.Table
		UIAxes_uninv_toe     matlab.ui.control.UIAxes
		UIAxes_uninv_heel    matlab.ui.control.UIAxes
		UIAxes_inv_toe       matlab.ui.control.UIAxes
		UIAxes_inv_heel      matlab.ui.control.UIAxes
	end

	
	properties (Access = private)
		
	end
	
	properties (Access = public)
		subject			% subject number
		session			% session (pre, mid, post, followup)
		trial_num		% vicon trial number
		side			% subjects affected leg/side

		vicon_data      % data read in from vicon csv file. See read_vicon_csv.m for the data format
		event_struct    % struct with hs & to times

		gc_data_tbl		% table with computed gait cycle info
	end
	
	methods (Access = private)
		
	end
	

	% Callbacks that handle component events
	methods (Access = private)

		% Code that executes after component creation
		function startupFcn(app, subject, session, trial, side, vicon_data, event_struct)
			app.subject = subject;
			app.session = session;
			app.trial_num = trial;
			app.side = side;
			app.vicon_data = vicon_data;
			app.event_struct = event_struct;

			draw_foot_markers(app)
			draw_hs_to_events(app)
			compute_gait_cycle_info(app)
			app.UITable_gc_info.ColumnName = app.gc_data_tbl.Properties.VariableNames;
			app.UITable_gc_info.Data = app.gc_data_tbl;
			
		end
	end

	% Component initialization
	methods (Access = private)

		% Create UIFigure and components
		function createComponents(app)
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			app.FootMarkersUIFigure = uifigure('Visible', 'off');
			app.FootMarkersUIFigure.Name = 'Foot Markers';
			app.UIAxes_inv_heel = uiaxes(app.FootMarkersUIFigure);
			title(app.UIAxes_inv_heel, 'Involved Heel')
			zlabel(app.UIAxes_inv_heel, 'Z')
			app.UIAxes_inv_toe = uiaxes(app.FootMarkersUIFigure);
			title(app.UIAxes_inv_toe, 'Involved Toe')
			zlabel(app.UIAxes_inv_toe, 'Z')
			app.UIAxes_uninv_heel = uiaxes(app.FootMarkersUIFigure);
			title(app.UIAxes_uninv_heel, 'Uninvolved Heel')
			zlabel(app.UIAxes_uninv_heel, 'Z')
			app.UIAxes_uninv_toe = uiaxes(app.FootMarkersUIFigure);
			title(app.UIAxes_uninv_toe, 'Uninvolved Toe')
			xlabel(app.UIAxes_uninv_toe, 'Time (s)')
			zlabel(app.UIAxes_uninv_toe, 'Z')
			app.UITable_gc_info = uitable(app.FootMarkersUIFigure);
			app.UITable_gc_info.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
			app.UITable_gc_info.RowName = {};
			% Show the figure after all components are created
			app.FootMarkersUIFigure.Visible = 'on';
		end
	end

	% App creation and deletion
	methods (Access = public)

		% Construct app
		function app = foot_markers(varargin)

			runningApp = getRunningApp(app);

			% Check for running singleton app
			if isempty(runningApp)

				% Create UIFigure and components
				createComponents(app)

				% Register the app with App Designer
				registerApp(app, app.FootMarkersUIFigure)

				% Execute the startup function
				runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
			else

				% Focus the running singleton app
				figure(runningApp.FootMarkersUIFigure)

				app = runningApp;
			end

			if nargout == 0
				clear app
			end
		end

		% Code that executes before app deletion
		function delete(app)

			% Delete UIFigure when app is deleted
			delete(app.FootMarkersUIFigure)
		end
	end
end